classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea_5            matlab.ui.control.TextArea
        TextArea_4            matlab.ui.control.TextArea
        DESCRIERELabel        matlab.ui.control.Label
        DATAPUBLICARIILabel   matlab.ui.control.Label
        stopachizitieButton   matlab.ui.control.Button
        startachizitieButton  matlab.ui.control.Button
        TextArea_3            matlab.ui.control.TextArea
        TextArea_2            matlab.ui.control.TextArea
        TextArea              matlab.ui.control.TextArea
        EDITURALabel          matlab.ui.control.Label
        TITLULabel            matlab.ui.control.Label
        AUTORLabel            matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
            vid         % Video input object
        isRunning   % Boolean to track acquisition status
        hImage      % Handle to image object in UIAxes
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vidObjs = imaqfind;

% Delete all existing video input objects
delete(vidObjs);
            app.vid = videoinput('winvideo', 1);
            app.vid.FramesPerTrigger = 1;
            app.vid.ReturnedColorspace = 'rgb';
            triggerconfig(app.vid, 'manual');
            
            % Initialize the image in UIAxes
            frame = getsnapshot(app.vid); % Get one frame to set up size
            app.hImage = image(app.UIAxes, zeros(size(frame), 'uint8'));
         
            axis(app.UIAxes, 'image');
          
            % Start the preview
            preview(app.vid, app.hImage);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
          
        end

        % Button pushed function: startachizitieButton
        function startachizitieButtonPushed(app, event)
           app.isRunning = true;
            start(app.vid);
            while app.isRunning
                img = getsnapshot(app.vid); % Get a frame
                [ocrResults,words] = scanareCoperta(img);

               
                 data = requestAPI(words);
                % words = strjoin(words, ' ');
              
                if ~isa(data, 'string')
                 disp(data);
                 try
                      app.TextArea.Value = data.authors;
                 catch
                    app.TextArea.Value = "";
                 end


                 try
                     app.TextArea_2.Value = data.title;
                 catch
                      app.TextArea_2.Value = "";
                 end

                 try
                       app.TextArea_3.Value = data.publisher;
                 catch
                      app.TextArea_3.Value = "";
                 end
                  try
                       app.TextArea_4.Value = data.publishedDate;
                 catch
                      app.TextArea_4.Value = "";
                  end
                   try
                       app.TextArea_5.Value = data.description;
                 catch
                      app.TextArea_5.Value = "";
                 end
                end
                pause(0.3); % Adjust this value to control the frame rate
            end
        end

        % Button pushed function: stopachizitieButton
        function stopachizitieButtonPushed(app, event)
            app.isRunning = false;
            stop(app.vid);
          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.AUTORLabel = uilabel(app.UIFigure);
            app.AUTORLabel.Text = 'AUTOR : ';
            app.TITLULabel = uilabel(app.UIFigure);
            app.TITLULabel.Text = 'TITLU : ';
            app.EDITURALabel = uilabel(app.UIFigure);
            app.EDITURALabel.Text = 'EDITURA :';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.startachizitieButton = uibutton(app.UIFigure, 'push');
            app.startachizitieButton.ButtonPushedFcn = createCallbackFcn(app, @startachizitieButtonPushed, true);
            app.startachizitieButton.Text = 'start achizitie';
            app.stopachizitieButton = uibutton(app.UIFigure, 'push');
            app.stopachizitieButton.ButtonPushedFcn = createCallbackFcn(app, @stopachizitieButtonPushed, true);
            app.stopachizitieButton.Text = 'stop achizitie';
            app.DATAPUBLICARIILabel = uilabel(app.UIFigure);
            app.DATAPUBLICARIILabel.Text = 'DATA PUBLICARII:';
            app.DESCRIERELabel = uilabel(app.UIFigure);
            app.DESCRIERELabel.Text = 'DESCRIERE:';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_5 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end